classdef app1_phantom_version < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RotatezSliderLabel  matlab.ui.control.Label
        RotatezSlider       matlab.ui.control.Slider
        RotateySliderLabel  matlab.ui.control.Label
        RotateySlider       matlab.ui.control.Slider
        RotatexSliderLabel  matlab.ui.control.Label
        RotatexSlider       matlab.ui.control.Slider
        plane3Spinner       matlab.ui.control.Spinner
        zaxisSpinnerLabel   matlab.ui.control.Label
        plane2Spinner       matlab.ui.control.Spinner
        yaxisSpinnerLabel   matlab.ui.control.Label
        plane1Spinner       matlab.ui.control.Spinner
        xaxisSpinnerLabel   matlab.ui.control.Label
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

% Global Variables
    properties (Access = private)

        init_origin = [0 0 0]; % Description
    end
    
    methods (Access = private)
       
      
        function update_p1(app)
            new_plane1 = app.plane1Spinner.Value;

            f_cube_obj= findobj(app.UIAxes4, 'Type', 'patch');
            cubePosition = get(f_cube_obj, 'Vertices');
            cubePosition(:, 1) = app.init_origin(:, 1);
            cubePosition(:, 1) = cubePosition(:, 1) + new_plane1;
            set(f_cube_obj, 'Vertices', cubePosition);
            

            f_p1_obj= findobj(app.UIAxes, 'Type', 'patch');
            slicePosition = get(f_p1_obj, 'Vertices');
            slicePosition(:, 1) = 0;
            slicePosition(:, 1) = slicePosition(:, 1) + new_plane1;
            set(f_p1_obj, 'Vertices', slicePosition);
        end


        function update_p2(app)
            new_plane2 = app.plane2Spinner.Value;

            f_cube_obj2= findobj(app.UIAxes4, 'Type', 'patch');
            cubePosition2 = get(f_cube_obj2, 'Vertices');
            cubePosition2(:, 2) = app.init_origin(:, 2);
            cubePosition2(:, 2) = cubePosition2(:, 2) + new_plane2; 
            set(f_cube_obj2, 'Vertices', cubePosition2);

            f_p2_obj= findobj(app.UIAxes2, 'Type', 'patch');
            slicePosition2 = get(f_p2_obj, 'Vertices');
            slicePosition2(:, 2) = 0;
            slicePosition2(:, 2) = slicePosition2(:, 2) + new_plane2;
            set(f_p2_obj, 'Vertices', slicePosition2);
        end


        function update_p3(app)
            new_plane3 = app.plane3Spinner.Value;

            f_cube_obj3= findobj(app.UIAxes4, 'Type', 'patch');
            cubePosition3= get(f_cube_obj3, 'Vertices');
            cubePosition3 (:, 3) = app.init_origin(:, 3);
            cubePosition3 (:, 3) = cubePosition3(:,3) + new_plane3;
            set(f_cube_obj3, 'Vertices', cubePosition3);

            f_p3_obj= findobj(app.UIAxes3, 'Type', 'patch');
            slicePosition3 = get(f_p3_obj, 'Vertices');
            slicePosition3(:, 3) = 0;
            slicePosition3(:, 3) = slicePosition3(:, 3) + new_plane3;
            set(f_p3_obj, 'Vertices', slicePosition3);            
        end


        function x_angle(app)
            new_x_angle = app.RotatexSlider.Value;

            rotate_f_cube_obj_X= findobj(app.UIAxes4, 'Type', 'patch');
            cubePositionX = get(rotate_f_cube_obj_X, 'Vertices');

            %to find center of rotation (centroid of a cube = sum dim/
            %faces)
            cent_x = (sum(cubePositionX(:,1))/8);
            cent_y = (sum(cubePositionX(:,2))/8);
            cent_z = (sum(cubePositionX(:,1))/8);
            centerRotation = [cent_x cent_y cent_z];

            rotated_x_vertices = rotatePoints(app,cubePositionX, centerRotation, [1 0 0], new_x_angle);
            set(rotate_f_cube_obj_X, 'Vertices',rotated_x_vertices);
            
        end


        function y_angle(app)
            new_y_angle = app.RotateySlider.Value;

            rotate_f_cube_obj_Y = findobj(app.UIAxes4, 'Type', 'patch');
            cubePositionY = get(rotate_f_cube_obj_Y, 'Vertices');

            cent_x = (sum(cubePositionY(:,1))/8);
            cent_y = (sum(cubePositionY(:,2))/8);
            cent_z = (sum(cubePositionY(:,1))/8);
            centerRotation = [cent_x cent_y cent_z];

            rotated_y_vertices = rotatePoints(app, cubePositionY, centerRotation, [0 1 0], new_y_angle);
            set(rotate_f_cube_obj_Y, 'Vertices', rotated_y_vertices);
        end


        function z_angle(app)
            new_z_angle = app.RotatezSlider.Value;

            rotate_f_cube_obj_Z = findobj(app.UIAxes4, 'Type','patch');
            cubePositionZ = get(rotate_f_cube_obj_Z, 'Vertices');

            cent_x = (sum(cubePositionZ(:,1))/8);
            cent_y = (sum(cubePositionZ(:,2))/8);
            cent_z = (sum(cubePositionZ(:,1))/8);
            centerRotation = [cent_x cent_y cent_z];

            rotated_z_vertices = rotatePoints(app, cubePositionZ, centerRotation, [0 0 1], new_z_angle);
            set(rotate_f_cube_obj_Z, 'Vertices', rotated_z_vertices);

        end

     
        function rotatePoints = rotatePoints(app,points, center, axis, angle)

            % Convert degrees to radians
            angle = deg2rad(angle);
    
            % Create a rotation matrix
            rot_Matrix = axang2rotm([axis, angle]);
    
            % Translate to origin
            trans_Points = bsxfun(@minus, points, center);
            
            % Rotate points
            rotatePoints = trans_Points * rot_Matrix;
            
            % Translate back to origin
            rotatePoints = bsxfun(@plus, rotatePoints, center);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %NOTE:
            % UIAxes = plane 1
            % UIAxes2 = plane 2
            % UIAxes3 = plane 3
            % UIAxes4 = cube

            %To restart
            cla(app.UIAxes);  
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);

            % Establish origin points
            init_origin1 = [0 0 0; 0 2 0; 2 2 0; 2 0 0; 0 0 2; 0 2 2; 2 2 2; 2 0 2];
            app.init_origin = init_origin1;
     
            %new_plane2 = 0;

            % Cube vertices
            vertices = [0 0 0; 0 2 0; 2 2 0; 2 0 0; 0 0 2; 0 2 2; 2 2 2; 2 0 2];

            % All faces of the cube
            faces = [1 2 3 4; 2 6 7 3; 4 3 7 8; 1 5 8 4; 1 2 6 5; 5 6 7 8];

            % UIAxes settings
            rotate3d(app.UIAxes4, 'on'); % cube
            view(app.UIAxes4, 3);
            axis(app.UIAxes4, 'equal');

            rotate3d(app.UIAxes, 'on'); % plane 1
            view(app.UIAxes, 3);
            axis(app.UIAxes, 'equal');

            rotate3d(app.UIAxes2, 'on'); % plane 2
            view(app.UIAxes2, 3);
            axis(app.UIAxes2, 'equal');

            rotate3d(app.UIAxes3, 'on'); % plane 3
            view(app.UIAxes3, 3);
            axis(app.UIAxes3, 'equal');
            
            % Cube Plot
            patch('Faces', faces, 'Vertices', vertices,  'FaceAlpha', 0.3, 'Parent', app.UIAxes4);

            xlabel(app.UIAxes4, 'X');
            ylabel(app.UIAxes4, 'Y');
            zlabel(app.UIAxes4, 'Z');
            title(app.UIAxes4, 'Cube Plot');
            
            % Backdrop box and Grid
            box(app.UIAxes4, 'on');
            grid(app.UIAxes4, 'on');
            xlim(app.UIAxes4, [-10, 10]);
            ylim(app.UIAxes4, [-10, 10]);
            zlim(app.UIAxes4, [-10, 10]);
            xticks(app.UIAxes4, -10:1:10);  
            yticks(app.UIAxes4, -10:1:10);  
            zticks(app.UIAxes4, -10:1:10);  

            box(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [-10, 10]);
            ylim(app.UIAxes, [-10, 10]);
            zlim(app.UIAxes, [-10, 10]);
            xticks(app.UIAxes, -10:1:10);  
            yticks(app.UIAxes, -10:1:10);  
            zticks(app.UIAxes, -10:1:10);  

            box(app.UIAxes2, 'on');
            grid(app.UIAxes2, 'on');
            xlim(app.UIAxes2, [-10, 10]);
            ylim(app.UIAxes2, [-10, 10]);
            zlim(app.UIAxes2, [-10, 10]);
            xticks(app.UIAxes2, -10:1:10);  
            yticks(app.UIAxes2, -10:1:10);  
            zticks(app.UIAxes2, -10:1:10); 

            box(app.UIAxes3, 'on');
            grid(app.UIAxes3, 'on');
            xlim(app.UIAxes3, [-10, 10]);
            ylim(app.UIAxes3, [-10, 10]);
            zlim(app.UIAxes3, [-10, 10]);
            xticks(app.UIAxes3, -10:1:10);  
            yticks(app.UIAxes3, -10:1:10);  
            zticks(app.UIAxes3, -10:1:10); 

            % Plane 1 slice plot
            
            %TO FIX. linspace, convexhull, v(mesh), and meshgrid doc?
            x_dat = vertices(:,1);
            y_dat = vertices(:,2);
            z_dat = vertices(:,3);
            vol_shape = alphaShape(x_dat, y_dat,z_dat);
            cube_v = volume(vol_shape);

            x_slice = 2;
            y_slice = [];
            z_slice = [];
            slice(x_dat, y_dat, z_dat, cube_v,x_slice, y_slice, z_slice);

            % Plane 2 slice plot
            ySlice = 1; % Select y-coordinate of slice
            sliceVertices = [0 ySlice 0; 2 ySlice 0; 2 ySlice 2; 0 ySlice 2];
            sliceFaces = [1 2 3 4];
            
            patch('Faces', sliceFaces, 'Vertices', sliceVertices, 'FaceAlpha', 0.3, 'Parent', app.UIAxes2);
            
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Z');
            zlabel(app.UIAxes2, 'Y');
            title(app.UIAxes2, 'Slice 2 Plot');
            
            % Plane 3 slice plot
            zSlice = 1; % Select z-coordinate of slice
            sliceVertices = [0 0 zSlice; 2 0 zSlice; 2 2 zSlice; 0 2 zSlice];
            sliceFaces = [1 2 3 4];
            
            patch('Faces', sliceFaces, 'Vertices', sliceVertices, 'FaceAlpha', 0.3, 'Parent', app.UIAxes3);
            
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            zlabel(app.UIAxes3, 'Z');
            title(app.UIAxes3, 'Slice 3 Plot');

            
            % To allow moving every time
            app.plane1Spinner.ValueChangedFcn = @(src, event) update_p1(app);
            app.plane2Spinner.ValueChangedFcn = @(src, event) update_p2(app);
            app.plane3Spinner.ValueChangedFcn = @(src, event) update_p3(app);

            % To allow rotation every time
            app.RotatexSlider.ValueChangedFcn = @(src, event) x_angle(app);
            app.RotateySlider.ValueChangedFcn = @(src, event) y_angle(app);
            app.RotatezSlider.ValueChangedFcn = @(src, event) z_angle(app);

        end

        % Value changed function: plane1Spinner
        function P1_Spinner(app, event)
            update_p1(app);
            app.plane1Spinner.ValueChangedFcn = @app.P1_Spinner;        
        end

        % Value changed function: plane2Spinner
        function P2_Spinner(app, event)
            update_p2(app);
            app.plane2Spinner.ValueChangedFcn = @app.P2_Spinner;
        end

        % Value changed function: plane3Spinner
        function P3_Spinner(app, event)
            update_p3(app);
            app.plane3Spinner.ValueChangedFcn = @app.P3_Spinner;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
        end

        % Value changed function: RotatexSlider
        function RotatexSliderValueChanged(app, event)
            x_angle(app);
            app.RotatexSlider.ValueChangedFcn = @app.RotatexSliderValueChanged;
        end

        % Value changed function: RotateySlider
        function RotateySliderValueChanged(app, event)
            y_angle(app);
            app.RotateySlider.ValueChangedFcn = @app.RotateySliderValueChanged;
         
        end

        % Value changed function: RotatezSlider
        function RotatezSliderValueChanged(app, event)
            z_angle(app);
            app.RotatezSlider.ValueChangedFcn = @app.RotatezSliderValueChanged;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'plane 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'plane 3')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XAxisLocation = 'origin';
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'plane 2')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'ORIGINAL ')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.xaxisSpinnerLabel = uilabel(app.UIFigure);
            app.xaxisSpinnerLabel.Text = 'x axis';
            app.plane1Spinner = uispinner(app.UIFigure);
            app.plane1Spinner.Step = 0.01;
            app.plane1Spinner.Limits = [-100 100];
            app.plane1Spinner.ValueDisplayFormat = '%.2f';
            app.plane1Spinner.ValueChangedFcn = createCallbackFcn(app, @P1_Spinner, true);
            app.yaxisSpinnerLabel = uilabel(app.UIFigure);
            app.yaxisSpinnerLabel.Text = 'y axis';
            app.plane2Spinner = uispinner(app.UIFigure);
            app.plane2Spinner.Step = 0.01;
            app.plane2Spinner.Limits = [-100 100];
            app.plane2Spinner.ValueDisplayFormat = '%.2f';
            app.plane2Spinner.ValueChangedFcn = createCallbackFcn(app, @P2_Spinner, true);
            app.zaxisSpinnerLabel = uilabel(app.UIFigure);
            app.zaxisSpinnerLabel.Text = 'z axis';
            app.plane3Spinner = uispinner(app.UIFigure);
            app.plane3Spinner.Step = 0.01;
            app.plane3Spinner.Limits = [-100 100];
            app.plane3Spinner.ValueDisplayFormat = '%.2f';
            app.plane3Spinner.ValueChangedFcn = createCallbackFcn(app, @P3_Spinner, true);
            app.RotatexSlider = uislider(app.UIFigure);
            app.RotatexSlider.Limits = [-180 180];
            app.RotatexSlider.ValueChangedFcn = createCallbackFcn(app, @RotatexSliderValueChanged, true);
            app.RotatexSliderLabel = uilabel(app.UIFigure);
            app.RotatexSliderLabel.Text = 'Rotate x';
            app.RotateySlider = uislider(app.UIFigure);
            app.RotateySlider.Limits = [-180 180];
            app.RotateySlider.ValueChangedFcn = createCallbackFcn(app, @RotateySliderValueChanged, true);
            app.RotateySliderLabel = uilabel(app.UIFigure);
            app.RotateySliderLabel.Text = 'Rotate y';
            app.RotatezSlider = uislider(app.UIFigure);
            app.RotatezSlider.Limits = [-180 180];
            app.RotatezSlider.ValueChangedFcn = createCallbackFcn(app, @RotatezSliderValueChanged, true);
            app.RotatezSliderLabel = uilabel(app.UIFigure);
            app.RotatezSliderLabel.Text = 'Rotate z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_phantom_version

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end